classdef ECG_V2_visual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        ECGSignalProcessingandparametersextractionLabel  matlab.ui.control.Label
        PlotcomparativeECGButton        matlab.ui.control.Button
        RRparametersLabel               matlab.ui.control.Label
        FilteringparametersLabel        matlab.ui.control.Label
        BPMparametersLabel              matlab.ui.control.Label
        PlototherparameterscalculationButton  matlab.ui.control.Button
        PlotbpmcalculationButton        matlab.ui.control.Button
        OtherparameterscalculationButton  matlab.ui.control.Button
        BPMcalculationButton            matlab.ui.control.Button
        Plotf0harmonicsfilteringButton  matlab.ui.control.Button
        Plotf0filteringButton           matlab.ui.control.Button
        f0harmonicsfilteringButton      matlab.ui.control.Button
        f0filteringButton               matlab.ui.control.Button
        PlotparametersLabel             matlab.ui.control.Label
        bpmwindowoverlapEditField       matlab.ui.control.NumericEditField
        bpmwindowoverlapEditFieldLabel  matlab.ui.control.Label
        bpmwindowEditField              matlab.ui.control.NumericEditField
        bpmwindowEditFieldLabel         matlab.ui.control.Label
        ECGpeakdistanceEditField        matlab.ui.control.NumericEditField
        ECGpeakdistanceEditFieldLabel   matlab.ui.control.Label
        ThECGpeakextractionEditField    matlab.ui.control.NumericEditField
        ThECGpeakextractionEditFieldLabel  matlab.ui.control.Label
        tminEditField                   matlab.ui.control.NumericEditField
        tminEditFieldLabel              matlab.ui.control.Label
        QEditField                      matlab.ui.control.NumericEditField
        QEditFieldLabel                 matlab.ui.control.Label
        f0EditField                     matlab.ui.control.NumericEditField
        f0EditFieldLabel                matlab.ui.control.Label
        fmaxplotsmallEditField          matlab.ui.control.NumericEditField
        fmaxplotsmallEditFieldLabel     matlab.ui.control.Label
        fmaxplotEditField               matlab.ui.control.NumericEditField
        fmaxplotEditFieldLabel          matlab.ui.control.Label
        fmaxEditField                   matlab.ui.control.NumericEditField
        fmaxEditFieldLabel              matlab.ui.control.Label
        FilenamewithoutmatEditField     matlab.ui.control.EditField
        FilenamewithoutmatEditFieldLabel  matlab.ui.control.Label
        FolderEditField                 matlab.ui.control.EditField
        FolderEditFieldLabel            matlab.ui.control.Label
        PlotECGButton                   matlab.ui.control.Button
        ExtractdataButton               matlab.ui.control.Button
        FlipsignalCheckBox              matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        t
        fs
        ECG
        stim
        N
        ECG_clean
        ecg_filtered 
        f0harm_flag = false;
        f0_flag = false;
        A_T;
        RR;
        SDNN;
        RMSSD;
        TP;
        LF;
        HF;
        f;
        pxx;
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExtractdataButton
        function ExtractdataButtonPushed(app, event)
            %%1. Load the data of the file from the folder
            folder = app.FolderEditField.Value;
            file = app.FilenamewithoutmatEditField.Value;
            % Cargar el archivo
            datos = load(folder+"/"+file+".mat");

            signal = datos.signal;
            app.fs = datos.sampling_rate;
            if(app.FlipsignalCheckBox.Value == true)
                app.ECG = -double(signal(:, 1));
            else
                app.ECG = double(signal(:, 1));
            end    
            app.stim = signal(:, 2);    
            app.N = length(app.ECG);


            app.t = (0:app.N-1)/app.fs;
            clear signal;
            clear datos;
            close all;
            clc;

            app.PlotECGButton.Visible = 'on';
            app.f0filteringButton.Visible = 'on';
            app.f0harmonicsfilteringButton.Visible = 'on';

        end

        % Button pushed function: PlotECGButton
        function PlotECGButtonPushed(app, event)
            %%fft calculation
           
            % Calculate FFT
            X = fft(app.ECG); % Calculate spectrum magnitude
            magX = abs(X)/app.N;     % Normalization
            magX = magX(1:app.N/2+1); % positive half spectrum
            magX(2:end-1) = 2*magX(2:end-1); % Compensate deleted energy
            f_figure = (0:app.N/2)*app.fs/app.N; % Frequency vector
            %%Plot fft of the signal with no filtering
            figure;
            plot(f_figure, magX);
            xlabel('Frequency (Hz)');
            ylabel('|X(f)|');
            title('Sectrum with no filtering');
            xlim([0, app.fmaxplotEditField.Value])
            grid on;
            
            figure;
            plot(f_figure, magX);
            xlabel('Frequency (Hz)');
            ylabel('|X(f)|');
            title('Sectrum with no filtering');
            xlim([0, app.fmaxplotsmallEditField.Value])
            grid on;


            figure;
            plot(app.t, app.ECG);
            xlabel('Time');
            ylabel('ECG');
            title('ECG vs time');
            % xlim([app.tminEditField.Value, app.tminEditField.Value+20])
        end

        % Button pushed function: f0harmonicsfilteringButton
        function f0harmonicsfilteringButtonPushed(app, event)

            app.ecg_filtered = app.ECG;  % copy of the signal
            
            % --- Harmonic Notch filtering---
            harmonics = app.f0EditField.Value:app.f0EditField.Value:app.fmaxEditField.Value;
            
            for f = harmonics
                % Avoid Notch at Nyquist exact (not valid)
                if f >= app.fs/2
                    continue;
                end
                wo = f/(app.fs/2);        % frecuencia normalizada
                bw = wo/app.QEditField.Value;            % ancho de banda normalizado
                [b, a] = iirnotch(wo, bw);
                app.ecg_filtered = filtfilt(b, a, app.ecg_filtered);  % filtrado en ambas direcciones
            end
            
            % --- Filtro pasa-bajo a 5 kHz ---
            lp_cutoff = app.fmaxEditField.Value;          % Hz
            [app.ecg_filtered, ~] = lowpass(app.ecg_filtered, lp_cutoff, app.fs, ImpulseResponse="iir", Steepness=0.95);
            
            
            % --- Filtro notch a 50 Hz
            [b,a] = iirnotch(50/(app.fs/2), 50/(app.fs/2)/app.QEditField.Value);
            app.ecg_filtered = filtfilt(b,a, app.ecg_filtered);

            app.Plotf0harmonicsfilteringButton.Visible = 'on';
            app.BPMcalculationButton.Visible = 'on';

            app.f0harm_flag = true;

            if(app.f0harm_flag && app.f0_flag)
                app.PlotcomparativeECGButton.Visible = 'on';
            end
        end

        % Button pushed function: Plotf0harmonicsfilteringButton
        function Plotf0harmonicsfilteringButtonPushed(app, event)
            %%fft calculatio with one filter
            app.N = length(app.ecg_filtered);
            magX = abs(fft(app.ecg_filtered))/app.N;     
            magX = magX(1:app.N/2+1);
            magX(2:end-1) = 2*magX(2:end-1);

            f_figure = (0:app.N/2)*app.fs/app.N; % Frequency vector
            %%Plot fft of the signal with f0 signal filtering
            figure;
            plot(f_figure, magX);
            xlabel('Frequency (Hz)');
            ylabel('|X(f)|');
            title('Sectrum with harmonics and f_max filtering');
            xlim([0, app.fmaxplotEditField.Value])
            grid on;
            
            figure;
            plot(f_figure, magX);
            xlabel('Frequency (Hz)');
            ylabel('|X(f)|');
            title('Sectrum with harmonics and f_max filtering');
            xlim([0, app.fmaxplotsmallEditField.Value])
            grid on;

            figure;
            plot(app.t, app.ecg_filtered);
            xlabel('Time');
            ylabel('ECG');
            title('ECG filtered f0 vs time');
            % xlim([app.tminEditField.Value, app.tminEditField.Value+20])
        end

        % Button pushed function: f0filteringButton
        function f0filteringButtonPushed(app, event)
            [b,a] = iirnotch(app.f0EditField.Value/(app.fs/2), app.f0EditField.Value/(app.fs/2)/app.QEditField.Value);
            app.ECG_clean = filtfilt(b,a, app.ECG);

            app.Plotf0filteringButton.Visible = 'on';


            app.f0_flag = true;

            if(app.f0harm_flag && app.f0_flag)
                app.PlotcomparativeECGButton.Visible = 'on';
            end
        end

        % Button pushed function: Plotf0filteringButton
        function Plotf0filteringButtonPushed(app, event)
            %%fft calculatio with one filter
            X = fft(app.ECG_clean);
            magX = abs(X)/app.N;     
            magX = magX(1:app.N/2+1);
            magX(2:end-1) = 2*magX(2:end-1);
            f_figure = (0:app.N/2)*app.fs/app.N; % Frequency vector
            %%Plot fft of the signal with f0 signal filtering
            figure;
            plot(f_figure, magX);
            xlabel('Frequency (Hz)');
            ylabel('|X(f)|');
            title('Sectrum with f0 filtering');
            xlim([0, app.fmaxplotEditField.Value])
            grid on;
            
            figure;
            plot(f_figure, magX);
            xlabel('Frequency (Hz)');
            ylabel('|X(f)|');
            title('Sectrum with f0 filtering');
            xlim([0, app.fmaxplotsmallEditField.Value])
            grid on;

            figure;
            plot(app.t, app.ECG_clean);
            xlabel('Time');
            ylabel('ECG');
            title('ECG filtered f0 vs time');
            % xlim([app.tminEditField.Value, app.tminEditField.Value+20])
        end

        % Button pushed function: PlotcomparativeECGButton
        function PlotcomparativeECGButtonPushed(app, event)
            figure;
            subplot(3,1,1);
            plot(app.t, app.ECG);
            title('Original ECG with stimulation');
            xlabel('Time (s)'); 
            ylabel('Amplitud');
            % xlim([t_min, t_min+20])
            
            subplot(3,1,2);
            plot(app.t, app.ECG_clean);
            title('Original ECG with stimulation f0 filtering');
            xlabel('Time (s)'); 
            ylabel('Amplitud');
            % xlim([t_min, t_min+20])
            
            subplot(3,1,3);
            plot(app.t, app.ecg_filtered);
            title('Original ECG with stimulation harmonics of f0, 50Hz and f_max filtering', 'Interpreter', 'none');
            xlabel('Time (s)'); 
            ylabel('Amplitud');
            % xlim([t_min, t_min+20])
        end

        % Button pushed function: BPMcalculationButton
        function BPMcalculationButtonPushed(app, event)
            %%Extraction of the R peaks from the ECG pulses
            [~, app.A_T]  = findpeaks(app.ecg_filtered, 'MinPeakHeight', app.ThECGpeakextractionEditField.Value, 'MinPeakDistance', app.ECGpeakdistanceEditField.Value);
            
            %%PPM calculation
            data_T = app.A_T;
            
            delta_t_muestra = app.bpmwindowEditField.Value*app.fs;
            ventana_muestra = app.bpmwindowoverlapEditField.Value*app.fs;
            
            steps = round(length(app.ECG)/delta_t_muestra);
            
            peaks_in_window = zeros(1, steps);
            mid_window = zeros(1, steps);
            
            for i=1:steps
                muestra_init = delta_t_muestra*(i-1);
                muestra_end = ventana_muestra + delta_t_muestra*(i-1);
                mid_window(i) =  app.bpmwindowEditField.Value + app.bpmwindowoverlapEditField.Value*(i-1);
                peaks_in_window(i) = sum(((data_T<muestra_end) & (data_T>muestra_init)))*(60/app.bpmwindowEditField.Value);
            end

            app.PlotbpmcalculationButton.Visible = 'on';
            app.OtherparameterscalculationButton.Visible = 'on';

        end

        % Button pushed function: OtherparameterscalculationButton
        function OtherparameterscalculationButtonPushed(app, event)
            
            %RR interval (NN): heart rate variability
            app.RR = diff(app.A_T)/app.fs;

            RR_filtered = app.RR(abs(diff(app.RR)./app.RR(1:end-1)) < 0.2);
            RR_filtered = RR_filtered(abs(RR_filtered - mean(RR_filtered)) < 3*std(RR_filtered));


            app.SDNN = std(RR_filtered);
            app.RMSSD = sqrt(mean(diff(RR_filtered).^2));


            t_B = cumsum(RR_filtered);
            tq = 0:0.01:t_B(end); % 100 Hz interpolación
            RR_interp = interp1(t_B, RR_filtered, tq, 'pchip');
            [app.pxx,app.f] = pwelch(RR_interp,[],[],[],100);
            app.TP = bandpower(app.pxx,app.fs,[0.16 3]);
            app.LF = bandpower(app.pxx,app.fs,[0.16 0.6]);
            app.HF = bandpower(app.pxx,app.fs,[0.6 3]);


            app.PlototherparameterscalculationButton.Visible = 'on';
        end

        % Button pushed function: PlototherparameterscalculationButton
        function PlototherparameterscalculationButtonPushed(app, event)
            figure;
            hold on
            plot(app.t, app.ECG/5)
            plot(app.t(app.A_T), app.ECG(app.A_T), '*');
            plot(app.t(app.A_T(1: end-1)), app.RR, 'b-o');
            title('Intervalos RR (N-N)');
            xlabel('Latido');
            ylabel('Tiempo (s)');
            ylim([-0.4, 0.8])
            legend('ECG', 'ECG peaks', 'RR')
            hold off
            grid on;

            figure;
            plot(app.f, app.pxx);
            title('Densidad espectral de potencia (HRV)');
            xlabel('Frecuencia (Hz)');
            ylabel('Potencia');
            xlim([0 3]); % Rango típico para HRV en roedores
            grid on;
            hold on;
            xline(0.16, '--r', 'LF inicio');
            xline(0.6, '--r', 'LF fin / HF inicio');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FlipsignalCheckBox = uicheckbox(app.UIFigure);
            app.FlipsignalCheckBox.Text = 'Flip signal';
            app.ExtractdataButton = uibutton(app.UIFigure, 'push');
            app.ExtractdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractdataButtonPushed, true);
            app.ExtractdataButton.Text = 'Extract data';
            app.PlotECGButton = uibutton(app.UIFigure, 'push');
            app.PlotECGButton.ButtonPushedFcn = createCallbackFcn(app, @PlotECGButtonPushed, true);
            app.PlotECGButton.Visible = 'off';
            app.PlotECGButton.Text = 'Plot ECG';
            app.FolderEditFieldLabel = uilabel(app.UIFigure);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.Value = 'raton';
            app.FilenamewithoutmatEditFieldLabel = uilabel(app.UIFigure);
            app.FilenamewithoutmatEditFieldLabel.Text = 'File name (without .mat)';
            app.FilenamewithoutmatEditField = uieditfield(app.UIFigure, 'text');
            app.FilenamewithoutmatEditField.Value = 'r5_izq_invasivo_aguja_roja_1mA';
            app.fmaxEditFieldLabel = uilabel(app.UIFigure);
            app.fmaxEditFieldLabel.Text = 'f max';
            app.fmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.fmaxEditField.Value = 550;
            app.fmaxplotEditFieldLabel = uilabel(app.UIFigure);
            app.fmaxplotEditFieldLabel.Text = 'f max plot';
            app.fmaxplotEditField = uieditfield(app.UIFigure, 'numeric');
            app.fmaxplotEditField.Value = 5000;
            app.fmaxplotsmallEditFieldLabel = uilabel(app.UIFigure);
            app.fmaxplotsmallEditFieldLabel.Text = 'f max plot small';
            app.fmaxplotsmallEditField = uieditfield(app.UIFigure, 'numeric');
            app.fmaxplotsmallEditField.Value = 100;
            app.f0EditFieldLabel = uilabel(app.UIFigure);
            app.f0EditFieldLabel.Text = 'f0';
            app.f0EditField = uieditfield(app.UIFigure, 'numeric');
            app.f0EditField.Value = 20.3293;
            app.QEditFieldLabel = uilabel(app.UIFigure);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.UIFigure, 'numeric');
            app.QEditField.Value = 200;
            app.tminEditFieldLabel = uilabel(app.UIFigure);
            app.tminEditFieldLabel.Text = 't min';
            app.tminEditField = uieditfield(app.UIFigure, 'numeric');
            app.tminEditField.Value = 850;
            app.ThECGpeakextractionEditFieldLabel = uilabel(app.UIFigure);
            app.ThECGpeakextractionEditFieldLabel.Text = 'Th ECG peak extraction';
            app.ThECGpeakextractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThECGpeakextractionEditField.Value = 0.2;
            app.ECGpeakdistanceEditFieldLabel = uilabel(app.UIFigure);
            app.ECGpeakdistanceEditFieldLabel.Text = 'ECG peak distance';
            app.ECGpeakdistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ECGpeakdistanceEditField.Value = 0.1;
            app.bpmwindowEditFieldLabel = uilabel(app.UIFigure);
            app.bpmwindowEditFieldLabel.Text = 'bpm window';
            app.bpmwindowEditField = uieditfield(app.UIFigure, 'numeric');
            app.bpmwindowEditField.Value = 10;
            app.bpmwindowoverlapEditFieldLabel = uilabel(app.UIFigure);
            app.bpmwindowoverlapEditFieldLabel.Text = 'bpm window overlap';
            app.bpmwindowoverlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.bpmwindowoverlapEditField.Value = 5;
            app.PlotparametersLabel = uilabel(app.UIFigure);
            app.PlotparametersLabel.Text = 'Plot parameters';
            app.f0filteringButton = uibutton(app.UIFigure, 'push');
            app.f0filteringButton.ButtonPushedFcn = createCallbackFcn(app, @f0filteringButtonPushed, true);
            app.f0filteringButton.Visible = 'off';
            app.f0filteringButton.Text = 'f0 filtering';
            app.f0harmonicsfilteringButton = uibutton(app.UIFigure, 'push');
            app.f0harmonicsfilteringButton.ButtonPushedFcn = createCallbackFcn(app, @f0harmonicsfilteringButtonPushed, true);
            app.f0harmonicsfilteringButton.Visible = 'off';
            app.f0harmonicsfilteringButton.Text = 'f0 + harmonics filtering';
            app.Plotf0filteringButton = uibutton(app.UIFigure, 'push');
            app.Plotf0filteringButton.ButtonPushedFcn = createCallbackFcn(app, @Plotf0filteringButtonPushed, true);
            app.Plotf0filteringButton.Visible = 'off';
            app.Plotf0filteringButton.Text = 'Plot f0 filtering';
            app.Plotf0harmonicsfilteringButton = uibutton(app.UIFigure, 'push');
            app.Plotf0harmonicsfilteringButton.ButtonPushedFcn = createCallbackFcn(app, @Plotf0harmonicsfilteringButtonPushed, true);
            app.Plotf0harmonicsfilteringButton.Visible = 'off';
            app.Plotf0harmonicsfilteringButton.Text = 'Plot f0 + harmonics filtering';
            app.BPMcalculationButton = uibutton(app.UIFigure, 'push');
            app.BPMcalculationButton.ButtonPushedFcn = createCallbackFcn(app, @BPMcalculationButtonPushed, true);
            app.BPMcalculationButton.Visible = 'off';
            app.BPMcalculationButton.Text = 'BPM calculation';
            app.OtherparameterscalculationButton = uibutton(app.UIFigure, 'push');
            app.OtherparameterscalculationButton.ButtonPushedFcn = createCallbackFcn(app, @OtherparameterscalculationButtonPushed, true);
            app.OtherparameterscalculationButton.Visible = 'off';
            app.OtherparameterscalculationButton.Text = 'Other parameters calculation';
            app.PlotbpmcalculationButton = uibutton(app.UIFigure, 'push');
            app.PlotbpmcalculationButton.Visible = 'off';
            app.PlotbpmcalculationButton.Text = 'Plot bpm calculation';
            app.PlototherparameterscalculationButton = uibutton(app.UIFigure, 'push');
            app.PlototherparameterscalculationButton.ButtonPushedFcn = createCallbackFcn(app, @PlototherparameterscalculationButtonPushed, true);
            app.PlototherparameterscalculationButton.Visible = 'off';
            app.PlototherparameterscalculationButton.Text = 'Plot other parameters calculation';
            app.BPMparametersLabel = uilabel(app.UIFigure);
            app.BPMparametersLabel.Text = 'BPM parameters';
            app.FilteringparametersLabel = uilabel(app.UIFigure);
            app.FilteringparametersLabel.Text = 'Filtering parameters';
            app.RRparametersLabel = uilabel(app.UIFigure);
            app.RRparametersLabel.Text = 'R-R parameters';
            app.PlotcomparativeECGButton = uibutton(app.UIFigure, 'push');
            app.PlotcomparativeECGButton.ButtonPushedFcn = createCallbackFcn(app, @PlotcomparativeECGButtonPushed, true);
            app.PlotcomparativeECGButton.Visible = 'off';
            app.PlotcomparativeECGButton.Text = 'Plot comparative ECG';
            app.ECGSignalProcessingandparametersextractionLabel = uilabel(app.UIFigure);
            app.ECGSignalProcessingandparametersextractionLabel.Text = 'ECG - Signal Processing and parameters extraction';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BlackOverWhite.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECG_V2_visual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end