classdef StrikeRealTime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ByAlimuLabel                    matlab.ui.control.Label
        ECGRealTimeAnalyzerforSTRIKEprojectLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        StartButton                     matlab.ui.control.Button
        PlotCheckBox                    matlab.ui.control.CheckBox
        CalculateCheckBox               matlab.ui.control.CheckBox
        StateEditField                  matlab.ui.control.EditField
        StateEditFieldLabel             matlab.ui.control.Label
        PreviewButton                   matlab.ui.control.Button
        ExtractCheckBox                 matlab.ui.control.CheckBox
        PTCheckBox                      matlab.ui.control.CheckBox
        BPMCheckBox                     matlab.ui.control.CheckBox
        sLabel_2                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        EndtimeEditField                matlab.ui.control.NumericEditField
        InitialtimeEditField            matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel           matlab.ui.control.Label
        InitialtimeEditFieldLabel       matlab.ui.control.Label
        IndicatorsindivicuallyCheckBox  matlab.ui.control.CheckBox
        IndicatorsbygroupsCheckBox      matlab.ui.control.CheckBox
        DefineinitalandendtimesforplottingfunctionsLabel  matlab.ui.control.Label
        IndicatorstogetherCheckBox      matlab.ui.control.CheckBox
        sLabel_4                        matlab.ui.control.Label
        SegmentoverlapEditField         matlab.ui.control.NumericEditField
        SegmentoverlapEditFieldLabel    matlab.ui.control.Label
        ECGCheckBox                     matlab.ui.control.CheckBox
        sLabel_3                        matlab.ui.control.Label
        SegmentDurationEditField        matlab.ui.control.NumericEditField
        SegmentDurationEditFieldLabel   matlab.ui.control.Label
        PQRSTpartitionedCheckBox        matlab.ui.control.CheckBox
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        PQRSTCheckBox                   matlab.ui.control.CheckBox
        LabelforplotsEditField          matlab.ui.control.EditField
        LabelforplotsEditFieldLabel     matlab.ui.control.Label
        SelectplotstobeshownLabel       matlab.ui.control.Label
        DifferentnamesCheckBox          matlab.ui.control.CheckBox
        InputFileNameEditField          matlab.ui.control.EditField
        InputFileNameEditFieldLabel     matlab.ui.control.Label
        InputFileFolderEditField        matlab.ui.control.EditField
        InputFileFolderEditFieldLabel   matlab.ui.control.Label
        MatlabLabel                     matlab.ui.control.Label
        SpikeFileNameEditField          matlab.ui.control.EditField
        SpikeFileNameEditFieldLabel     matlab.ui.control.Label
        SpikeFileFolderEditField        matlab.ui.control.EditField
        SpikeFileFolderEditFieldLabel   matlab.ui.control.Label
        Pythonspike2Label               matlab.ui.control.Label
    end

    
    properties (Access = public)
        time; % Description
        ECG;
        stim_reference;
        locs_P;
        locs_Q;
        locs_R;
        locs_S;
        locs_T;
        locs_T_init;
        locs_T_end;
        locs_P_init;
        locs_P_end;
        PR;
        PS; 
        RS;
        RT;
        QRS;
        QT;
        ST;
        RT_voltage;
        RR;
        time_intervals_bpm;
        BPM; 
        R_intervals;
        leg;
        fs;
    end
    
    methods (Access = private)

        function run_python_code(app)
            
            % Crear el comando para ejecutar el script Python con las variables
            comando = sprintf(['python convert_to_matlab.py "%s" "%s" "%s" "%s"'], app.SpikeFileFolderEditField.Value, app.SpikeFileNameEditField.Value, app.InputFileFolderEditField.Value, app.InputFileNameEditField.Value);
            
            % Ejecutar el comando en la terminal
            system(comando);

        end
        
        function call_ECG_PQRST(app)

            app.StateEditField.Value = "Init";

            if app.ExtractCheckBox.Value == true
                run_python_code(app);
                app.StateEditField.Value = "Matlab file generated";
            end

            if app.CalculateCheckBox.Value == true
                
                app.StateEditField.Value = "Calculating values";
                EXP_folder = app.InputFileFolderEditField.Value;
                file = app.InputFileNameEditField.Value;
                data = load(EXP_folder+"/"+file+".mat");
                th = app.ThresholdEditField.Value;
                app.leg = app.LabelforplotsEditField.Value;
                segment_duration = app.SegmentDurationEditField.Value;
                delta_time = app.SegmentoverlapEditField.Value;
                
            
                [app.ECG, app.stim_reference, ~, app.fs, app.time] = extract_data(data);
                [app.locs_P, app.locs_Q, app.locs_R, app.locs_S, app.locs_T, app.locs_T_init, app.locs_T_end, app.locs_P_init, app.locs_P_end, app.PR, app.PS, app.RS, app.RT, app.QRS, app.QT, app.ST, app.RT_voltage, app.RR, app.time_intervals_bpm, app.BPM, app.R_intervals] = functions_ECG_PQRST(app.ECG, th, app.fs, app.leg, segment_duration, delta_time);
                app.StateEditField.Value = "Values Calculated";
            end

            if app.PlotCheckBox.Value
                

                functions_plotting_booleaned(app.time, app.ECG, app.stim_reference, app.locs_P, app.locs_Q, app.locs_R, app.locs_S, app.locs_T, app.locs_T_init, app.locs_T_end, app.locs_P_init, app.locs_P_end, app.PR, app.PS, app.RS, app.RT, app.QRS, app.QT, app.ST, app.RT_voltage, app.RR, app.time_intervals_bpm, app.BPM, app.leg, app.InitialtimeEditField.Value, app.EndtimeEditField.Value, ...
                app.PQRSTCheckBox.Value, app.PQRSTpartitionedCheckBox.Value, app.ECGCheckBox.Value, app.IndicatorstogetherCheckBox.Value, app.IndicatorsbygroupsCheckBox.Value, app.IndicatorsindivicuallyCheckBox.Value, app.BPMCheckBox.Value, app.PTCheckBox.Value)
                app.StateEditField.Value = "Functions plotted";
            end


        end

        function preview_ECG(app)
                EXP_folder = app.InputFileFolderEditField.Value;
                file = app.InputFileNameEditField.Value;
                data = load(EXP_folder+"/"+file+".mat");
                [app.ECG, app.stim_reference, ~, app.fs, app.time] = extract_data(data);
                plot_ECG_only(app.time, app.ECG, app.LabelforplotsEditField.Value, 180, 183);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            call_ECG_PQRST(app);
            run_python_code(app);
        end

        % Value changed function: DifferentnamesCheckBox
        function DifferentnamesCheckBoxValueChanged(app, event)
            value = app.DifferentnamesCheckBox.Value;
            if value == true
                app.InputFileNameEditField.Editable = "on";
            else
                app.InputFileNameEditField.Editable = "off";
                app.InputFileNameEditField.Value = app.SpikeFileNameEditField.Value;
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            preview_ECG(app)
        end

        % Value changing function: SpikeFileNameEditField
        function SpikeFileNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if app.DifferentnamesCheckBox.Value==false
                app.InputFileNameEditField.Value = app.SpikeFileNameEditField.Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Pythonspike2Label = uilabel(app.UIFigure);
            app.Pythonspike2Label.Text = 'Python (spike2)';
            app.SpikeFileFolderEditFieldLabel = uilabel(app.UIFigure);
            app.SpikeFileFolderEditFieldLabel.Text = ' Spike File Folder';
            app.SpikeFileFolderEditField = uieditfield(app.UIFigure, 'text');
            app.SpikeFileFolderEditField.Value = 'D:\12_BIOS\STRIKE\ECG_recording_analysis\Python_anlisis_ECG\02_CTB_setup_experiments\EXP005';
            app.SpikeFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.SpikeFileNameEditFieldLabel.Text = 'Spike File Name';
            app.SpikeFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.SpikeFileNameEditField.ValueChangingFcn = createCallbackFcn(app, @SpikeFileNameEditFieldValueChanging, true);
            app.SpikeFileNameEditField.Value = 'raton0_control_anestesia';
            app.MatlabLabel = uilabel(app.UIFigure);
            app.MatlabLabel.Text = 'Matlab';
            app.InputFileFolderEditFieldLabel = uilabel(app.UIFigure);
            app.InputFileFolderEditFieldLabel.Text = 'Input File Folder';
            app.InputFileFolderEditField = uieditfield(app.UIFigure, 'text');
            app.InputFileFolderEditField.Value = 'D:\12_BIOS\STRIKE\ECG_recording_analysis\Python_anlisis_ECG\MAT\EXP005';
            app.InputFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.InputFileNameEditFieldLabel.Text = 'Input File Name';
            app.InputFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.InputFileNameEditField.Editable = 'off';
            app.InputFileNameEditField.Value = 'raton0_control_anestesia';
            app.DifferentnamesCheckBox = uicheckbox(app.UIFigure);
            app.DifferentnamesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DifferentnamesCheckBoxValueChanged, true);
            app.DifferentnamesCheckBox.Text = 'Different names';
            app.SelectplotstobeshownLabel = uilabel(app.UIFigure);
            app.SelectplotstobeshownLabel.Text = 'Select plots to be shown';
            app.LabelforplotsEditFieldLabel = uilabel(app.UIFigure);
            app.LabelforplotsEditFieldLabel.Text = 'Label for plots';
            app.LabelforplotsEditField = uieditfield(app.UIFigure, 'text');
            app.LabelforplotsEditField.Value = 'R0: control';
            app.PQRSTCheckBox = uicheckbox(app.UIFigure);
            app.PQRSTCheckBox.Text = 'PQRST';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.Value = 0.2;
            app.PQRSTpartitionedCheckBox = uicheckbox(app.UIFigure);
            app.PQRSTpartitionedCheckBox.Text = 'PQRST partitioned';
            app.SegmentDurationEditFieldLabel = uilabel(app.UIFigure);
            app.SegmentDurationEditFieldLabel.Text = 'Segment Duration';
            app.SegmentDurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.SegmentDurationEditField.Value = 60;
            app.sLabel_3 = uilabel(app.UIFigure);
            app.sLabel_3.Text = '[s]';
            app.ECGCheckBox = uicheckbox(app.UIFigure);
            app.ECGCheckBox.Text = 'ECG';
            app.SegmentoverlapEditFieldLabel = uilabel(app.UIFigure);
            app.SegmentoverlapEditFieldLabel.Text = 'Segment overlap';
            app.SegmentoverlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.SegmentoverlapEditField.Value = 50;
            app.sLabel_4 = uilabel(app.UIFigure);
            app.sLabel_4.Text = '[s]';
            app.IndicatorstogetherCheckBox = uicheckbox(app.UIFigure);
            app.IndicatorstogetherCheckBox.Text = 'Indicators together';
            app.DefineinitalandendtimesforplottingfunctionsLabel = uilabel(app.UIFigure);
            app.DefineinitalandendtimesforplottingfunctionsLabel.Text = 'Define inital and end times for plotting functions';
            app.IndicatorsbygroupsCheckBox = uicheckbox(app.UIFigure);
            app.IndicatorsbygroupsCheckBox.Text = 'Indicators by groups';
            app.IndicatorsindivicuallyCheckBox = uicheckbox(app.UIFigure);
            app.IndicatorsindivicuallyCheckBox.Text = 'Indicators indivicually';
            app.InitialtimeEditFieldLabel = uilabel(app.UIFigure);
            app.InitialtimeEditFieldLabel.Text = 'Initial time';
            app.EndtimeEditFieldLabel = uilabel(app.UIFigure);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.InitialtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndtimeEditField.Value = 2000;
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = '[s]';
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = '[s]';
            app.BPMCheckBox = uicheckbox(app.UIFigure);
            app.BPMCheckBox.Text = 'BPM';
            app.PTCheckBox = uicheckbox(app.UIFigure);
            app.PTCheckBox.Visible = 'off';
            app.PTCheckBox.Text = 'P-T ';
            app.ExtractCheckBox = uicheckbox(app.UIFigure);
            app.ExtractCheckBox.Text = 'Extract';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.StateEditFieldLabel = uilabel(app.UIFigure);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.UIFigure, 'text');
            app.StateEditField.Editable = 'off';
            app.StateEditField.Value = 'Init';
            app.CalculateCheckBox = uicheckbox(app.UIFigure);
            app.CalculateCheckBox.Text = 'Calculate';
            app.PlotCheckBox = uicheckbox(app.UIFigure);
            app.PlotCheckBox.Text = 'Plot';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'B105.svg');
            app.ECGRealTimeAnalyzerforSTRIKEprojectLabel = uilabel(app.UIFigure);
            app.ECGRealTimeAnalyzerforSTRIKEprojectLabel.Text = 'ECG Real Time Analyzer for STRIKE project';
            app.ByAlimuLabel = uilabel(app.UIFigure);
            app.ByAlimuLabel.Text = 'By: Alimu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StrikeRealTime

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end